classdef auto_corr_gen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        GenerateDataTab                 matlab.ui.container.Tab
        TransMat                        matlab.ui.control.Table
        TransitionRateMatrixflowsLabel  matlab.ui.control.Label
        LengthofTracessLabel            matlab.ui.control.Label
        LengthTraceNum                  matlab.ui.control.Label
        Rates                           matlab.ui.control.Table
        EmissionRatesRNAsLabel          matlab.ui.control.Label
        BootstrapsCheckBox              matlab.ui.control.CheckBox
        LoadPlotsButton                 matlab.ui.control.Button
        SavePlotsButton                 matlab.ui.control.Button
        InitProbs                       matlab.ui.control.Table
        InitialProbabilityDistributionLabel  matlab.ui.control.Label
        ElongationTimesEditFieldLabel   matlab.ui.control.Label
        ElongationTimesEditField        matlab.ui.control.NumericEditField
        TimeResolutionsEditFieldLabel   matlab.ui.control.Label
        TimeResolutionsEditField        matlab.ui.control.NumericEditField
        LengthofTracespointsEditFieldLabel  matlab.ui.control.Label
        LengthofTracespointsEditField   matlab.ui.control.NumericEditField
        NumberofTracesEditFieldLabel    matlab.ui.control.Label
        NumberofTracesEditField         matlab.ui.control.NumericEditField
        NumberofStatesEditFieldLabel    matlab.ui.control.Label
        NumberofStatesEditField         matlab.ui.control.NumericEditField
        fluoRNAEditFieldLabel           matlab.ui.control.Label
        fluoRNAEditField                matlab.ui.control.NumericEditField
        GenerateListBoxLabel            matlab.ui.control.Label
        GenerateListBox                 matlab.ui.control.ListBox
        RiseTimeEditFieldLabel          matlab.ui.control.Label
        RiseTimeEditField               matlab.ui.control.NumericEditField
        NoiseEditFieldLabel             matlab.ui.control.Label
        NoiseEditField                  matlab.ui.control.NumericEditField
        MaxDelayEditFieldLabel          matlab.ui.control.Label
        MaxDelayEditField               matlab.ui.control.NumericEditField
        CutEditFieldLabel               matlab.ui.control.Label
        CutEditField                    matlab.ui.control.NumericEditField
        LearningTab                     matlab.ui.container.Tab
        GuessElongationTimeCheckBox     matlab.ui.control.CheckBox
        UseRealParamsCheckBox           matlab.ui.control.CheckBox
        ElongationTimeGuessesLabel      matlab.ui.control.Label
        MinimumEditFieldLabel           matlab.ui.control.Label
        MinimumEditField                matlab.ui.control.NumericEditField
        MaximumEditFieldLabel           matlab.ui.control.Label
        MaximumEditField                matlab.ui.control.NumericEditField
        StepEditFieldLabel              matlab.ui.control.Label
        StepEditField                   matlab.ui.control.NumericEditField
        RiseTimeGuessesLabel            matlab.ui.control.Label
        MinimumEditField_2Label         matlab.ui.control.Label
        MinimumEditField_2              matlab.ui.control.NumericEditField
        MaximumEditField_2Label         matlab.ui.control.Label
        MaximumEditField_2              matlab.ui.control.NumericEditField
        StepEditField_2Label            matlab.ui.control.Label
        StepEditField_2                 matlab.ui.control.NumericEditField
        NumberofStatesEditField_2Label  matlab.ui.control.Label
        NumberofStatesEditField_2       matlab.ui.control.NumericEditField
        TransMatTest                    matlab.ui.control.Table
        TransitionRateMatrixflowsLabel_2  matlab.ui.control.Label
        fluoRNAEditField_2Label         matlab.ui.control.Label
        fluoRNAEditField_2              matlab.ui.control.NumericEditField
        RatesTest                       matlab.ui.control.Table
        EmissionRatesRNAsLabel_2        matlab.ui.control.Label
        InitProbsTest                   matlab.ui.control.Table
        InitialProbabilityDistributionLabel_2  matlab.ui.control.Label
        ElongationTimeEstimateEditFieldLabel  matlab.ui.control.Label
        ElongationTimeEstimateEditField  matlab.ui.control.NumericEditField
        RiseTimeEstimateEditFieldLabel  matlab.ui.control.Label
        RiseTimeEstimateEditField       matlab.ui.control.NumericEditField
        UITable                         matlab.ui.control.Table
        GeneratePlotsButton             matlab.ui.control.Button
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.TransMat.Data = [-0.0115,  0.0095,  0.0000; ...
                                 0.0115, -0.0180,  0.0440; ...
                                 0.0000,  0.0085, -0.0440];
            app.Rates.Data = [.001,.2,.4];
            app.InitProbs.Data = [1,0,0];
            
            app.TransMatTest.Data = app.TransMat.Data;
            app.RatesTest.Data = app.Rates.Data;
            app.InitProbsTest.Data = app.InitProbs.Data;
        end

        % Callback function
        function UITableCellEdit(app, event)
            
        end

        % Value changed function: NumberofStatesEditField
        function NumberofStatesEditFieldValueChanged(app, event)
            value = app.NumberofStatesEditField.Value;
            cols = cell(value);
            for i = 1:value
                cols{i} = "State" + num2str(i);
            end
            %resets transition matrix table
            app.TransMat.ColumnName = cols;
            app.TransMat.Data = abs(normrnd(0,.1,[value,value]));
            for v = 1:value
                app.TransMat.Data(v,v) = 0;
                app.TransMat.Data(v,v) = -sum(app.TransMat.Data(:,v));
            end
            
            %resets emission rates table
            app.Rates.ColumnName = cols;
            dat = random('uniform',0,1,[1,value-1]);
            app.Rates.Data = [.0001, sort(dat)];
            
            %resets intial probability distribution table
            app.InitProbs.ColumnName = cols;
            dat = random('uniform',0,1,[1,value]);
            dat = dat / sum(dat);
            app.InitProbs.Data = dat;
        end

        % Value changed function: LengthofTracespointsEditField
        function LengthofTracespointsEditFieldValueChanged(app, event)
            traceLength = app.LengthofTracespointsEditField.Value;
            timeRes = app.TimeResolutionsEditField.Value;
            app.LengthTraceNum.Text = num2str(traceLength * timeRes);
        end

        % Value changed function: TimeResolutionsEditField
        function TimeResolutionsEditFieldValueChanged(app, event)
            traceLength = app.LengthofTracespointsEditField.Value;
            timeRes = app.TimeResolutionsEditField.Value;
            app.LengthTraceNum.Text = num2str(traceLength * timeRes);
        end

        % Button pushed function: LoadPlotsButton
        function LoadPlotsButtonPushed(app, event)
            [filename,pathname] = uigetfile;
            filepath = [pathname filename];
            fig = openfig(filepath);
        end

        % Button pushed function: GeneratePlotsButton
        function GeneratePlotsButtonPushed(app, event)
            elong_time = app.ElongationTimesEditField.Value;
            time_res = app.TimeResolutionsEditField.Value;
            points_per_trace = app.LengthofTracespointsEditField.Value;
            num_traces = app.NumberofTracesEditField.Value;
            trans_mat = app.TransMat.Data;
            num_states = app.NumberofStatesEditField.Value;
            fluo_per_rna = app.fluoRNAEditField.Value;
            rna_per_sec = app.Rates.Data;
            init_dist = app.InitProbs.Data;
            rise_time = app.RiseTimeEditField.Value;
            noise = app.NoiseEditField.Value;
            addpath('src/');
            addpath('src/utilities/');
            traces = gen_data(elong_time, time_res, points_per_trace, num_traces, num_states, ...
                              trans_mat, rna_per_sec, fluo_per_rna, rise_time, init_dist, ...
                              noise);
            
            cor_ops = app.GenerateListBox.Value;
            auto = false;
            first = false;
            second = false;
            if ismember('Autocorrelation',cor_ops)
                auto = true;
            end
            if ismember('First Derivative',cor_ops)
                first = true;
            end
            if ismember('Second Derivative', cor_ops)
                second = true;
            end
            max_delay = app.MaxDelayEditField.Value;
            cut = app.CutEditField.Value;
            bootstraps = app.BootstrapsCheckBox.Value;
            gen_auto_cor(traces, auto, first, second, bootstraps, max_delay,cut);
            
            % estimates elongation/rise time if box is checked
            if app.GuessElongationTimeCheckBox.Value
                auto_cor = auto_corr_m_calc_norm(traces, max_delay);
                elong_min = app.MinimumEditField.Value;
                elong_max = app.MaximumEditField.Value;
                elong_step = app.StepEditField.Value;
                rise_min = app.MinimumEditField_2.Value;
                rise_max = app.MaximumEditField_2.Value;
                rise_step = app.StepEditField_2.Value;
                if app.UseRealParamsCheckBox.Value
                    [elong,rise,all] = grid_monte_carlo(time_res, points_per_trace, num_traces, num_states, ...
                              trans_mat, rna_per_sec, fluo_per_rna, init_dist, auto_cor, elong_min, ...
                              elong_max, elong_step, rise_min, rise_max, rise_step);
                else
                    num_states2 = app.NumberofStatesEditField_2.Value;
                    trans_mat2 = app.TransMatTest.Data;
                    rna_per_sec2 = app.RatesTest.Data;
                    fluo_per_rna2 = app.fluoRNAEditField_2.Value;
                    init_dist2 = app.InitProbsTest.Data;
                    [elong,rise,all] = grid_monte_carlo(time_res, points_per_trace, num_traces, num_states2, ...
                              trans_mat2, rna_per_sec2, fluo_per_rna2, init_dist2, auto_cor, elong_min, ...
                              elong_max, elong_step, rise_min, rise_max, rise_step);
                end
                app.ElongationTimeEstimateEditField.Value = elong;
                app.RiseTimeEstimateEditField.Value = rise;
                rows = elong_min:elong_step:elong_max;
                cols = rise_min:rise_step:rise_max;
                app.UITable.RowName = rows;
                app.UITable.ColumnName = cols;
                app.UITable.Data = all;
            end
            
        end

        % Value changed function: NumberofStatesEditField_2
        function NumberofStatesEditField_2ValueChanged(app, event)
            value = app.NumberofStatesEditField_2.Value;
            cols = cell(value);
            for i = 1:value
                cols{i} = "State" + num2str(i);
            end
            %resets transition matrix table
            app.TransMatTest.ColumnName = cols;
            app.TransMatTest.Data = abs(normrnd(0,.1,[value,value]));
            for v = 1:value
                app.TransMatTest.Data(v,v) = 0;
                app.TransMatTest.Data(v,v) = -sum(app.TransMatTest.Data(:,v));
            end
            
            %resets emission rates table
            app.RatesTest.ColumnName = cols;
            dat = random('uniform',0,1,[1,value-1]);
            app.RatesTest.Data = [.0001, sort(dat)];
            
            %resets intial probability distribution table
            app.InitProbsTest.ColumnName = cols;
            dat = random('uniform',0,1,[1,value]);
            dat = dat / sum(dat);
            app.InitProbsTest.Data = dat;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GenerateDataTab = uitab(app.TabGroup);
            app.GenerateDataTab.Title = 'Generate Data';
            app.TransMat = uitable(app.GenerateDataTab);
            app.TransMat.ColumnName = {'State 1'; 'State 2'; 'State 3'};
            app.TransMat.RowName = {};
            app.TransMat.ColumnEditable = true;
            app.TransitionRateMatrixflowsLabel = uilabel(app.GenerateDataTab);
            app.TransitionRateMatrixflowsLabel.Text = 'Transition Rate Matrix (flow/s)';
            app.LengthofTracessLabel = uilabel(app.GenerateDataTab);
            app.LengthofTracessLabel.Text = 'Length of Traces (s)';
            app.LengthTraceNum = uilabel(app.GenerateDataTab);
            app.LengthTraceNum.Text = '200';
            app.Rates = uitable(app.GenerateDataTab);
            app.Rates.ColumnName = {'State 1'; 'State 2'; 'State 3'};
            app.Rates.RowName = {};
            app.Rates.ColumnEditable = true;
            app.EmissionRatesRNAsLabel = uilabel(app.GenerateDataTab);
            app.EmissionRatesRNAsLabel.Text = 'Emission Rates (RNA/s)';
            app.BootstrapsCheckBox = uicheckbox(app.GenerateDataTab);
            app.BootstrapsCheckBox.Text = 'Bootstraps';
            app.LoadPlotsButton = uibutton(app.GenerateDataTab, 'push');
            app.LoadPlotsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPlotsButtonPushed, true);
            app.LoadPlotsButton.Text = 'Load Plots';
            app.SavePlotsButton = uibutton(app.GenerateDataTab, 'push');
            app.SavePlotsButton.Text = 'Save Plots';
            app.InitProbs = uitable(app.GenerateDataTab);
            app.InitProbs.ColumnName = {'State 1'; 'State 2'; 'State 3'};
            app.InitProbs.RowName = {};
            app.InitProbs.ColumnEditable = true;
            app.InitialProbabilityDistributionLabel = uilabel(app.GenerateDataTab);
            app.InitialProbabilityDistributionLabel.Text = 'Initial Probability Distribution';
            app.ElongationTimesEditFieldLabel = uilabel(app.GenerateDataTab);
            app.ElongationTimesEditFieldLabel.Text = 'Elongation Time (s)';
            app.ElongationTimesEditField = uieditfield(app.GenerateDataTab, 'numeric');
            app.ElongationTimesEditField.Limits = [0 Inf];
            app.ElongationTimesEditField.Value = 160;
            app.TimeResolutionsEditFieldLabel = uilabel(app.GenerateDataTab);
            app.TimeResolutionsEditFieldLabel.Text = 'Time Resolution (s)';
            app.TimeResolutionsEditField = uieditfield(app.GenerateDataTab, 'numeric');
            app.TimeResolutionsEditField.ValueChangedFcn = createCallbackFcn(app, @TimeResolutionsEditFieldValueChanged, true);
            app.TimeResolutionsEditField.Value = 20;
            app.LengthofTracespointsEditFieldLabel = uilabel(app.GenerateDataTab);
            app.LengthofTracespointsEditFieldLabel.Text = 'Length of Traces (points)';
            app.LengthofTracespointsEditField = uieditfield(app.GenerateDataTab, 'numeric');
            app.LengthofTracespointsEditField.Limits = [1 Inf];
            app.LengthofTracespointsEditField.ValueDisplayFormat = '%.0f';
            app.LengthofTracespointsEditField.ValueChangedFcn = createCallbackFcn(app, @LengthofTracespointsEditFieldValueChanged, true);
            app.LengthofTracespointsEditField.Value = 100;
            app.NumberofTracesEditFieldLabel = uilabel(app.GenerateDataTab);
            app.NumberofTracesEditFieldLabel.Text = 'Number of Traces ';
            app.NumberofTracesEditField = uieditfield(app.GenerateDataTab, 'numeric');
            app.NumberofTracesEditField.Limits = [1 Inf];
            app.NumberofTracesEditField.ValueDisplayFormat = '%.0f';
            app.NumberofTracesEditField.Value = 50;
            app.NumberofStatesEditFieldLabel = uilabel(app.GenerateDataTab);
            app.NumberofStatesEditFieldLabel.Text = 'Number of States';
            app.NumberofStatesEditField = uieditfield(app.GenerateDataTab, 'numeric');
            app.NumberofStatesEditField.Limits = [0 Inf];
            app.NumberofStatesEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofStatesEditFieldValueChanged, true);
            app.NumberofStatesEditField.Value = 3;
            app.fluoRNAEditFieldLabel = uilabel(app.GenerateDataTab);
            app.fluoRNAEditFieldLabel.Text = 'fluo/RNA';
            app.fluoRNAEditField = uieditfield(app.GenerateDataTab, 'numeric');
            app.fluoRNAEditField.Limits = [0 Inf];
            app.fluoRNAEditField.Value = 350;
            app.GenerateListBoxLabel = uilabel(app.GenerateDataTab);
            app.GenerateListBoxLabel.Text = 'Generate';
            app.GenerateListBox = uilistbox(app.GenerateDataTab);
            app.GenerateListBox.Items = {'Autocorrelation', 'First Derivative', 'Second Derivative'};
            app.GenerateListBox.Multiselect = 'on';
            app.GenerateListBox.Value = {'Autocorrelation'};
            app.RiseTimeEditFieldLabel = uilabel(app.GenerateDataTab);
            app.RiseTimeEditFieldLabel.Text = 'Rise Time';
            app.RiseTimeEditField = uieditfield(app.GenerateDataTab, 'numeric');
            app.RiseTimeEditField.Limits = [0 Inf];
            app.RiseTimeEditField.Value = 40;
            app.NoiseEditFieldLabel = uilabel(app.GenerateDataTab);
            app.NoiseEditFieldLabel.Text = 'Noise';
            app.NoiseEditField = uieditfield(app.GenerateDataTab, 'numeric');
            app.NoiseEditField.Limits = [0 Inf];
            app.MaxDelayEditFieldLabel = uilabel(app.GenerateDataTab);
            app.MaxDelayEditFieldLabel.Text = 'Max Delay';
            app.MaxDelayEditField = uieditfield(app.GenerateDataTab, 'numeric');
            app.MaxDelayEditField.Limits = [1 Inf];
            app.MaxDelayEditField.Value = 20;
            app.CutEditFieldLabel = uilabel(app.GenerateDataTab);
            app.CutEditFieldLabel.Text = 'Cut';
            app.CutEditField = uieditfield(app.GenerateDataTab, 'numeric');
            app.CutEditField.Limits = [0 Inf];
            app.LearningTab = uitab(app.TabGroup);
            app.LearningTab.Title = 'Learning';
            app.GuessElongationTimeCheckBox = uicheckbox(app.LearningTab);
            app.GuessElongationTimeCheckBox.Text = 'Guess Elongation Time';
            app.UseRealParamsCheckBox = uicheckbox(app.LearningTab);
            app.UseRealParamsCheckBox.Text = 'Use Real Params';
            app.ElongationTimeGuessesLabel = uilabel(app.LearningTab);
            app.ElongationTimeGuessesLabel.Text = 'Elongation Time Guesses';
            app.MinimumEditFieldLabel = uilabel(app.LearningTab);
            app.MinimumEditFieldLabel.Text = 'Minimum';
            app.MinimumEditField = uieditfield(app.LearningTab, 'numeric');
            app.MinimumEditField.Limits = [0 Inf];
            app.MinimumEditField.Value = 120;
            app.MaximumEditFieldLabel = uilabel(app.LearningTab);
            app.MaximumEditFieldLabel.Text = 'Maximum';
            app.MaximumEditField = uieditfield(app.LearningTab, 'numeric');
            app.MaximumEditField.Limits = [0 Inf];
            app.MaximumEditField.Value = 200;
            app.StepEditFieldLabel = uilabel(app.LearningTab);
            app.StepEditFieldLabel.Text = 'Step';
            app.StepEditField = uieditfield(app.LearningTab, 'numeric');
            app.StepEditField.Limits = [0 Inf];
            app.StepEditField.Value = 10;
            app.RiseTimeGuessesLabel = uilabel(app.LearningTab);
            app.RiseTimeGuessesLabel.Text = 'Rise Time Guesses';
            app.MinimumEditField_2Label = uilabel(app.LearningTab);
            app.MinimumEditField_2Label.Text = 'Minimum';
            app.MinimumEditField_2 = uieditfield(app.LearningTab, 'numeric');
            app.MinimumEditField_2.Limits = [0 Inf];
            app.MaximumEditField_2Label = uilabel(app.LearningTab);
            app.MaximumEditField_2Label.Text = 'Maximum';
            app.MaximumEditField_2 = uieditfield(app.LearningTab, 'numeric');
            app.MaximumEditField_2.Limits = [0 Inf];
            app.MaximumEditField_2.Value = 40;
            app.StepEditField_2Label = uilabel(app.LearningTab);
            app.StepEditField_2Label.Text = 'Step';
            app.StepEditField_2 = uieditfield(app.LearningTab, 'numeric');
            app.StepEditField_2.Value = 5;
            app.NumberofStatesEditField_2Label = uilabel(app.LearningTab);
            app.NumberofStatesEditField_2Label.Text = 'Number of States';
            app.NumberofStatesEditField_2 = uieditfield(app.LearningTab, 'numeric');
            app.NumberofStatesEditField_2.Limits = [0 Inf];
            app.NumberofStatesEditField_2.ValueChangedFcn = createCallbackFcn(app, @NumberofStatesEditField_2ValueChanged, true);
            app.NumberofStatesEditField_2.Value = 3;
            app.TransMatTest = uitable(app.LearningTab);
            app.TransMatTest.ColumnName = {'State 1'; 'State 2'; 'State 3'};
            app.TransMatTest.RowName = {};
            app.TransMatTest.ColumnEditable = true;
            app.TransitionRateMatrixflowsLabel_2 = uilabel(app.LearningTab);
            app.TransitionRateMatrixflowsLabel_2.Text = 'Transition Rate Matrix (flow/s)';
            app.fluoRNAEditField_2Label = uilabel(app.LearningTab);
            app.fluoRNAEditField_2Label.Text = 'fluo/RNA';
            app.fluoRNAEditField_2 = uieditfield(app.LearningTab, 'numeric');
            app.fluoRNAEditField_2.Limits = [0 Inf];
            app.fluoRNAEditField_2.Value = 350;
            app.RatesTest = uitable(app.LearningTab);
            app.RatesTest.ColumnName = {'State 1'; 'State 2'; 'State 3'};
            app.RatesTest.RowName = {};
            app.RatesTest.ColumnEditable = true;
            app.EmissionRatesRNAsLabel_2 = uilabel(app.LearningTab);
            app.EmissionRatesRNAsLabel_2.Text = 'Emission Rates (RNA/s)';
            app.InitProbsTest = uitable(app.LearningTab);
            app.InitProbsTest.ColumnName = {'State 1'; 'State 2'; 'State 3'};
            app.InitProbsTest.RowName = {};
            app.InitProbsTest.ColumnEditable = true;
            app.InitialProbabilityDistributionLabel_2 = uilabel(app.LearningTab);
            app.InitialProbabilityDistributionLabel_2.Text = 'Initial Probability Distribution';
            app.ElongationTimeEstimateEditFieldLabel = uilabel(app.LearningTab);
            app.ElongationTimeEstimateEditFieldLabel.Text = 'Elongation Time Estimate';
            app.ElongationTimeEstimateEditField = uieditfield(app.LearningTab, 'numeric');
            app.ElongationTimeEstimateEditField.Editable = 'off';
            app.RiseTimeEstimateEditFieldLabel = uilabel(app.LearningTab);
            app.RiseTimeEstimateEditFieldLabel.Text = 'Rise Time Estimate';
            app.RiseTimeEstimateEditField = uieditfield(app.LearningTab, 'numeric');
            app.RiseTimeEstimateEditField.Editable = 'off';
            app.UITable = uitable(app.LearningTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.GeneratePlotsButton = uibutton(app.UIFigure, 'push');
            app.GeneratePlotsButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratePlotsButtonPushed, true);
            app.GeneratePlotsButton.Text = 'Generate Plots';
        end
    end

    methods (Access = public)

        % Construct app
        function app = auto_corr_gen

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end